classdef SpreadGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        PlaybackLabel       matlab.ui.control.Label
        text13_2            matlab.ui.control.Label
        epsBarText          matlab.ui.control.EditField
        triMeshCheckbox     matlab.ui.control.CheckBox
        discreteCheckbox    matlab.ui.control.CheckBox
        text13              matlab.ui.control.Label
        volTolText          matlab.ui.control.EditField
        batchRun            matlab.ui.control.Button
        saveData            matlab.ui.control.Button
        totalTimeText       matlab.ui.control.EditField
        text8               matlab.ui.control.Label
        NText               matlab.ui.control.EditField
        polyForceDecayText  matlab.ui.control.EditField
        muText              matlab.ui.control.EditField
        text6               matlab.ui.control.Label
        dtText              matlab.ui.control.EditField
        text5               matlab.ui.control.Label
        fPolyText           matlab.ui.control.EditField
        text4               matlab.ui.control.Label
        f0Text              matlab.ui.control.EditField
        text3               matlab.ui.control.Label
        text1               matlab.ui.control.Label
        r0Text              matlab.ui.control.EditField
        startSim            matlab.ui.control.Button
        simSlider           matlab.ui.control.Slider
        text9               matlab.ui.control.Label
        text7               matlab.ui.control.Label
        simPlot             matlab.ui.control.UIAxes
    end


    methods (Access = private)
        function saveFEMLog(app, handles, savePath)
            outString{1} = sprintf('Number of iterations: %d', handles.numIt);
            outString{2} = sprintf('dt = %.4f (in terms of Courant condition)',handles.dt);
            outString{3} = sprintf('N = %.2f',handles.N);
            outString{4} = sprintf('r0 = %.2f nm',handles.r0Adh);
            outString{5} = sprintf('f0 = %.4f',handles.f0);
            outString{6} = sprintf('fPoly = %.2f',handles.fPoly);
            outString{7} = sprintf('mu (viscosity) = %.2f',handles.mu);
            outString{8} = sprintf('Poly force decay (um) = %.2f', handles.polyForceDecay);
            outString{9} = sprintf('FEM Solution');
            fid = fopen(fullfile(savePath,'simSettings.txt'),'w');
            fprintf(fid,'%s\r\n',outString{:});
            fclose(fid);
        end

        function handles = updateDisplay(app, handles)
            axes(handles.simPlot)
            cla
            hold on
            title(sprintf('%.2fs',handles.timeVec(handles.curFrame)))
            r = handles.r;
            z = handles.z;
            IEN = handles.IEN;
            nodesPerEl = handles.nodesPerEl;
            IEN = reshape(IEN,[nodesPerEl, length(IEN)/nodesPerEl]);
            %     plot([-1.5 1.5], [-.5 -.5], 'k')
            switch nodesPerEl
                case 4
                    numCols = IEN(4,1)-1; % for first element in the top left corner
                    numRows = length(r)/numCols;
                    xMesh = reshape(r,[numCols,numRows])';
                    yMesh = reshape(z,[numCols,numRows])';
                    surf(xMesh,yMesh,zeros(size(xMesh)),'FaceColor','none','EdgeColor','k')
                    surf(-xMesh,yMesh,zeros(size(xMesh)),'FaceColor','none','EdgeColor','k')
                    view([0 90])
                case 3
                    triplot(IEN,xVec,yVec,'Color','k')
                    triplot(IEN,-xVec,yVec,'Color','k')
            end

            if handles.noSurf
                xlim(app.simPlot,[-1.5 1.5])
                ylim(app.simPlot,[-.6 .6])
            elseif handles.squishTest
                xLower = [-1.5, -1.5, 1.5, 1.5];
                yLower = [-.1, 0, 0, -.1];
                patch(app.simPlot,'XData',xLower,'YData',yLower,'FaceColor',[.5 .5 .5],'EdgeColor','k')
                xUpper = [-1.5, -1.5, 1.5, 1.5];
                yUpper = [max(z), max(z)+.1, max(z)+.1, max(z)];
                patch(app.simPlot,'XData',xUpper,'YData',yUpper,'FaceColor',[.5 .5 .5],'EdgeColor','k')
                xlim(app.simPlot,[-1.5 1.5])
                ylim(app.simPlot,[-.1 1.5])
            else
                x = [-1.5, -1.5, 1.5, 1.5];
                y = [-.1, 0, 0, -.1];
                patch(app.simPlot,'XData',x,'YData',y','FaceColor','k')
                xlim(app.simPlot,[-1.5 1.5])
                ylim(app.simPlot,[-.1 1.5])
            end
            daspect([1 1 1])
            drawnow
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SpreadGUI_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to SpreadGUI (see VARARGIN)

            % Choose default command line output for SpreadGUI
            handles.output = hObject;
            % initialize parameters
            handles.r0Adh = str2double(get(handles.r0Text,'String'));
            handles.N = str2double(get(handles.NText,'String'));
            handles.f0 = str2double(get(handles.f0Text,'String'));
            handles.fPoly = str2double(get(handles.fPolyText,'String'));
            handles.dt = str2double(get(handles.dtText,'String'));
            handles.dtRef = handles.dt * .2;
            handles.totalTime = str2double(get(handles.totalTimeText,'String'));
            handles.numIt = round(handles.totalTime/handles.dtRef);
            handles.mu = str2double(get(handles.muText,'String'));
            handles.polyForceDecay = str2double(get(handles.polyForceDecayText,'String'));
            handles.volTol = str2double(get(handles.volTolText,'String'));
            handles.discreteLogic = get(handles.discreteCheckbox,'Value');
            handles.timeVec = zeros(1,round(handles.totalTime/.2));
            handles.NShift = 0;
            handles.timeDecay = inf;
            handles.random = false;
            handles.v_rStored = {};
            handles.v_zStored = {};
            handles.pStored = {};
            handles.meshRef = .2;
            handles.meshRefineTest = false;
            handles.meshVal = 0.05;
            handles.K_Ca = 0.05;
            handles.epsFactor = str2double(app.epsBarText.Value);
            handles.triMesh = app.triMeshCheckbox.Value;
            if handles.triMesh
                handles.nodesPerEl = 3;
            else
                handles.nodesPerEl = 4;
            end
%             choice = questdlg('Start with sphere or choose shape from past analysis?', 'Starting shape?',...
%                 'Sphere', 'Load past shape','Other test', 'Sphere');
            choice = 'Sphere';
            handles.d0 = 8.5e-4;
            switch choice
                case 'Sphere'
                    % parameterize boundary points for half shell, in this case, want finer
                    % material points for area close to the surface
                    sTotal = 2*pi/4;
                    startSpacing = .02;
                    sSampleInc = sTotal/(round(sTotal/(startSpacing/4)));
                    sParam = 0:sSampleInc:sTotal;
                    handles.r = .5*sin(pi*sParam/sTotal);
                    handles.z = .5*cos(pi*sParam/sTotal) - .05*handles.r0Adh*1e-7/handles.d0; % offset for small initial contact area
                    handles.z = handles.z + 0.5;
                    handles.z(handles.z < 0) = 0;

                    % space boundary
                    minSpacing = handles.meshVal*handles.r0Adh*1e-7/handles.d0;
                    [r,z] = respaceNodes(handles.r,handles.z,0,[startSpacing,minSpacing]);
                    [vMeshStruct,pMeshStruct] = meshFromBound(r,z,handles.nodesPerEl);
                    handles.r = vMeshStruct.xVec;
                    handles.z = vMeshStruct.yVec;
                    handles.IEN = vMeshStruct.IEN;
                    handles.vMeshStruct = vMeshStruct;
                    handles.pMeshStruct = pMeshStruct;
                    
                    handles.rStored = {};
                    handles.zStored = {};
                    handles.IENStored = {};
                    handles.rStored{1} = handles.r;
                    handles.zStored{1} = handles.z;
                    handles.IENStored{1} = handles.IEN;
                    handles.coeffStored = {};
                    handles.noSurf = false;
                    handles.squishTest = false;
                    handles.squishForce = 0;
                case 'Load past shape'
                    dataPath = uigetdir('','Open folder with saved data');
                    rStruct = load(fullfile(dataPath,'rData.mat'));
                    zStruct = load(fullfile(dataPath,'zData.mat'));
                    timeStruct = load(fullfile(dataPath,'timeVec.mat'));
                    handles.rStored = rStruct.rStored;
                    maxIdx = length(handles.rStored);
                    for i = 2:length(handles.rStored)
                        if isempty(handles.rStored{i})
                            maxIdx = i-1;
                            break
                        end
                    end
                    handles.rStored = handles.rStored(1:maxIdx);
                    handles.zStored = zStruct.zStored(1:maxIdx);
                    handles.timeVec = timeStruct.timeVec(1:maxIdx);
                    pStruct = load(fullfile(dataPath,'pData.mat'));
                    v_rStruct = load(fullfile(dataPath,'v_rData.mat'));
                    v_zStruct = load(fullfile(dataPath,'v_zData.mat'));
                    handles.pStored = pStruct.pStored(1:maxIdx-1);
                    handles.v_rStored = v_rStruct.v_rStored(1:maxIdx-1);
                    handles.v_zStored = v_zStruct.v_zStored(1:maxIdx-1);
                    handles.r = handles.rStored{1};
                    handles.z = handles.zStored{1};

                    % read in log file, if it's there
                    try fid = fopen(fullfile(dataPath,'simSettings.txt'),'r');
                        handles = loadLog(handles,fid);
                    end

                    set(handles.r0Text,'String',sprintf('%.2f',handles.r0Adh))
                    sParam = zeros(1,length(handles.r));
                    for i = 2:length(handles.r)
                        sParam(i) = sParam(i-1) + sqrt((handles.r(i)-handles.r(i-1)).^2 ...
                            +(handles.z(i)-handles.z(i-1)).^2);
                    end
                    handles.noSurf = false;
                    handles.squishTest = false;
                    handles.squishForce = 0;
                    handles.coeffStored = {};
            end
            handles.curFrame = 1;
            handles = updateDisplay(app, handles);
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: NText
        function NText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to NText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of NText as text
            %        str2double(get(hObject,'String')) returns contents of NText as a double
            handles.N = str2double(get(hObject,'String'));
            guidata(hObject,handles)
        end

        % Callback function
        function PVExpText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to PVExpText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of PVExpText as text
            %        str2double(get(hObject,'String')) returns contents of PVExpText as a double
            handles.PVExp = str2double(get(hObject,'String'));
            guidata(hObject,handles)
        end

        % Callback function
        function PVMultText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to PVMultText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of PVMultText as text
            %        str2double(get(hObject,'String')) returns contents of PVMultText as a double
            handles.PVMult = str2double(get(handles.PVMultText,'String'));
            guidata(hObject,handles)
        end

        % Button pushed function: batchRun
        function batchRun_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to batchRun (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if handles.noSurf
                testValsCell = inputdlg('Enter pipette sizes to test, separated by commas','Enter values');
                testVals = textscan(testValsCell{1},'%.6f,');
                testVals = double(testVals{:});
                savePath = uigetdir('','Choose location to save folders with batch data');
                for i = 1:length(testVals)
                    handles.pipetteSize = testVals(i);
                    rPipette = (handles.pipetteSize * 1e-4 / 2) / handles.d0;
                    PMNVol = (4/3) * pi * (.5)^3; % volume stays constant, assume 8.5um spherical cell undeformed
                    endsVol = (4/3) * pi * rPipette^3; % volume of hemispherical ends
                    volCyl = PMNVol - endsVol; %remaining volume is cylinder
                    lengthCyl = volCyl / (pi * rPipette^2);
                    % parameterize boundary points for half shell
                    sTotal = pi*rPipette + lengthCyl;
                    numNodes = round(sTotal / .02);
                    sGap = sTotal / numNodes;
                    sParam = 0:sGap:sTotal;
                    r = zeros(1,length(sParam));
                    z = zeros(1,length(sParam));
                    logic1 = sParam < pi*rPipette/2;
                    r(logic1) = rPipette * sin(sParam(logic1) / rPipette);
                    z(logic1) = -rPipette * (1 - cos(sParam(logic1) / rPipette));
                    logic2 = (sParam >= pi*rPipette/2) & (sParam <= (pi*rPipette/2 + lengthCyl));
                    r(logic2) = rPipette;
                    z(logic2) = -rPipette - (sParam(logic2) - (pi*rPipette/2));
                    logic3 = sParam > (pi*rPipette/2 + lengthCyl);
                    r(logic3) = rPipette * (sin((sParam(logic3) - lengthCyl) / rPipette));
                    z(logic3) = -rPipette - (lengthCyl - rPipette * cos((sParam(logic3)-lengthCyl) / rPipette));
                    handles.r = r;
                    handles.z = z - mean(z);
                    handles.rStored = {};
                    handles.zStored = {};
                    handles.rStored{1} = handles.r;
                    handles.zStored{1} = handles.z;
                    handles.coeffStored = {};
                    outStruct = spreadCellCalcNew(handles);
                    rStored = outStruct.rStored;
                    zStored = outStruct.zStored;
                    coeffStored = outStruct.coeffStored;
                    errorStored = outStruct.errorStored;
                    timeVec = outStruct.timeVec;
                    curFolder = sprintf('%.2fumPipette',handles.pipetteSize);
                    mkdir(savePath,curFolder);
                    save(fullfile(savePath,curFolder,'rData.mat'),'rStored')
                    save(fullfile(savePath,curFolder,'zData.mat'),'zStored')
                    save(fullfile(savePath,curFolder,'coeffVals.mat'),'coeffStored')
                    save(fullfile(savePath,curFolder,'errorStored.mat'),'errorStored')
                    save(fullfile(savePath,curFolder,'timeVec.mat'),'timeVec')
                    saveLog(app, handles,fullfile(savePath,curFolder))
                end
            elseif handles.squishTest
                testValsCell = inputdlg('Enter squishing forces to test, separated by commas','Enter values');
                testVals = textscan(testValsCell{1},'%.6f,');
                testVals = double(testVals{:});
                savePath = uigetdir('','Choose location to save folders with batch data');
                for i = 1:length(testVals)
                    handles.squishForce = testVals(i);
                    handles.rStored = handles.rStored(1);
                    handles.zStored = handles.zStored(1);
                    handles.r = handles.rStored{1};
                    handles.z = handles.zStored{1};
                    handles.coeffStored = {};
                    outStruct = spreadCellCalcNew(handles);
                    rStored = outStruct.rStored;
                    zStored = outStruct.zStored;
                    coeffStored = outStruct.coeffStored;
                    errorStored = outStruct.errorStored;
                    curFolder = sprintf('%.2fsquishForce',handles.squishForce);
                    mkdir(savePath,curFolder);
                    save(fullfile(savePath,curFolder,'rData.mat'),'rStored')
                    save(fullfile(savePath,curFolder,'zData.mat'),'zStored')
                    save(fullfile(savePath,curFolder,'coeffVals.mat'),'coeffStored')
                    save(fullfile(savePath,curFolder,'errorStored.mat'),'errorStored')
                    saveLog(app, handles,fullfile(savePath,curFolder))
                end
            else
                handles.NShift = 0;
                handles.timeDecay = inf;
                singDoub = questdlg('Single or double batch?','Double batch?','Single','Double','Double with shift','Single');
                NShiftRepeats = 0;
                NShiftLogic = false;
                if strcmp(singDoub,'Double')
                    numLoops = 2;
                elseif strcmp(singDoub,'Double with shift')
                    numLoops = 2;
                    shiftText = inputdlg('Enter number of shifts','Enter number of shifts');
                    NShiftRepeats = str2double(shiftText{1});
                    NShiftLogic = true;
                else
                    numLoops = 1;
                end
                storeTestVals = cell(1,2);
                paramChoice = cell(1,2);
                for loop = 1:numLoops
                    paramChoice{loop} = questdlg('Which parameter do you want to vary?','Which parameter?',...
                        'N','NShift','K_Ca','N');
                    if strcmp(paramChoice{loop},'NShift')
                        shiftText = inputdlg('Enter number of shifts','Enter number of shifts');
                        shiftVal = str2double(shiftText{1});
                        testValsCell = inputdlg('Now enter N vals to test, separated by commas','Enter N vals');
                        testVals = textscan(testValsCell{1},'%.6f,');
                        storeTestVals{2} = double(testVals{:});
                        minN = min(storeTestVals{2});
                        paramChoice{2} = 'N';
                        paramChoice{1} = 'NShift';
                        %         shiftVal = shiftVal * 1e-3;
                        %         storeTestVals{1} = 0:shiftVal:sqrt(1/minN);
                        numLoops = 2;
                        break
                    elseif strcmp(paramChoice{loop},'Numerical')
                        %             dtValsCell = inputdlg('Now enter dt vals to test, separated by commas','Enter dt vals');
                        %             dtVals = textscan(dtValsCell{1},'%.6f,');
                        %             dtVals = double(dtVals{:});
                        dtVals = [];
                        meshValsCell = inputdlg('Now enter eps vals to test, separated by commas','Enter eps vals');
                        meshVals = textscan(meshValsCell{1},'%.6f,');
                        meshVals = double(meshVals{:});
                        paramChoice{1} = 'Numerical';
                        storeTestVals{1} = [dtVals, meshVals];
                        paramChoice{2} = 'Numerical BZ vs PZ';
                        storeTestVals{2} = {'BZ','PZ'};
                        numLoops = 2;
                    else
                        testValsCell = inputdlg(sprintf('Enter values to test for %s, separated by commas',paramChoice{loop}),'Enter values');
                        testVals = textscan(testValsCell{1},'%.6f,');
                        storeTestVals{loop} = double(testVals{:});
                    end
                end
                if numLoops == 1
                    storeTestVals{2} = handles.N;
                    paramChoice{2} = 'N';
                end
                if NShiftRepeats == 0
                    repeats = inputdlg('How many repeats?:');
                    repeats = str2double(repeats{1});
                    if repeats > 1
                        handles.random = true;
                    else
                        handles.random = false;
                    end
                else
                    handles.random = false;
                    repeats = NShiftRepeats;
                end
                savePath = uigetdir('','Choose location to save folders with batch data');
                handles.rStart = handles.r;
                handles.zStart = handles.z;
                handles.meshRefineTest = false;
                for k = 1:repeats
                    for i = 1:length(storeTestVals{2}) % if only one batch, this doesn't affect inner loop
                        switch paramChoice{2}
                            case 'N'
                                handles.N = storeTestVals{2}(i);
                                handles.dtRef = str2double(get(handles.dtText,'String'));
                                handles.numIt = round(handles.totalTime/handles.dtRef) + 1;
                                if strcmp(paramChoice{1},'NShift')
                                    shiftGap = sqrt(1/handles.N) / shiftVal;
                                    storeTestVals{1} = shiftGap:shiftGap:sqrt(1/handles.N);
                                end
                                if NShiftLogic
                                    shiftGap = sqrt(1/handles.N) / NShiftRepeats;
                                    handles.NShift = (k-1) * shiftGap;
                                end
                            case 'r0Adh'
                                handles.r0Adh = storeTestVals{2}(i);
                            case 'numTerms'
                                handles.numTerms = round(storeTestVals{2}(i));
                            case 'fPoly'
                                handles.fPoly = storeTestVals{2}(i);
                            case 'f0'
                                handles.f0 = storeTestVals{2}(i);
                            case 'timeDecay'
                                handles.timeDecay = storeTestVals{2}(i);
                            case 'Length decay'
                                handles.polyForceDecay = storeTestVals{2}(i);
                                handles.fPoly = 2400 / handles.polyForceDecay;
                            case 'dt'
                                handles.dt = storeTestVals{2}(i);
                            case 'Mesh Refinement'
                                handles.meshRefineTest = true;
                                handles.meshVal = storeTestVals{2}(i);
                            case 'Eps factor'
                                handles.epsFactor = storeTestVals{2}(i);
                            case 'K_Ca'
                                handles.K_Ca = storeTestVals{2}(i);
                            case 'Numerical BZ vs PZ'
                                switch storeTestVals{2}{i}
                                    case 'BZ'
                                        handles.N = 1000;
                                        handles.fPoly = 0;
                                        handles.mu = 2000;
                                        handles.totalTime = 200;
                                    case 'PZ'
                                        handles.N = 0;
                                        handles.fPoly = 3000;
                                        handles.mu = 16600;
                                        handles.totalTime = 150;
                                end
                                handles.numIt = round(handles.totalTime/handles.dtRef) + 1;
                        end
                        for j = 1:length(storeTestVals{1})
                            handles.rStored = {};
                            handles.zStored = {};
                            handles.rStored{1} = handles.rStart;
                            handles.zStored{1} = handles.zStart;
                            handles.r = handles.rStart;
                            handles.z = handles.zStart;
                            switch paramChoice{1}
                                case 'N'
                                    handles.N = storeTestVals{1}(j);
                                    handles.dtRef = str2double(get(handles.dtText,'String'));
                                    handles.numIt = round(handles.totalTime/handles.dtRef) + 1;
                                    if NShiftLogic
                                        shiftGap = sqrt(1/handles.N) / NShiftRepeats;
                                        handles.NShift = (k-1) * shiftGap;
                                    end
                                case 'r0Adh'
                                    handles.r0Adh = storeTestVals{1}(j);
                                case 'numTerms'
                                    handles.numTerms = round(storeTestVals{1}(j));
                                case 'NShift'
%                                     maxShift = sqrt(1/handles.N);
%                                     if storeTestVals{1}(j) < maxShift
                                        handles.NShift = storeTestVals{1}(j);
%                                     else
%                                         continue;
%                                     end
                                case 'fPoly'
                                    handles.fPoly = storeTestVals{1}(j);
                                case 'f0'
                                    handles.f0 = storeTestVals{1}(j);
                                case 'timeDecay'
                                    handles.timeDecay = storeTestVals{1}(j);
                                case 'Length decay'
                                    handles.polyForceDecay = storeTestVals{1}(j);
                                    handles.fPoly = 2400 / handles.polyForceDecay;
                                case 'dt'
                                    handles.dt = storeTestVals{1}(j);
                                case 'Mesh Refinement'
                                    handles.meshRefineTest = true;
                                    handles.meshVal = storeTestVals{1}(j);
                                case 'Eps factor'
                                    handles.epsFactor = storeTestVals{1}(j);
                                case 'K_Ca'
                                    handles.K_Ca = storeTestVals{1}(j);
                                case 'Numerical'
                                    if j <= length(dtVals)
                                        handles.dt = storeTestVals{1}(j);
                                        handles.meshRefineTest = false;
                                        handles.meshVal = 0.05;
                                    else
                                        %                             handles.dt = 0.1;
                                        %                             handles.meshRefineTest = true;
                                        handles.epsFactor = storeTestVals{1}(j);
                                    end
                            end
                            outStruct = spreadCellCalcFEM_new(handles);
%                             outStruct = spreadCellTwoPhase(handles);
                            if numLoops == 1
                                curFolder = sprintf('%s%.5f',paramChoice{1},storeTestVals{1}(j));
                            else
                                if strcmp(paramChoice{1},'Numerical')
                                    if j <= length(dtVals)
                                        curFolder = sprintf('dt%.5f_Numerical%s',storeTestVals{1}(j),...
                                            storeTestVals{2}{i});
                                    else
                                        curFolder = sprintf('MeshRefine%.5f_Numerical%s',storeTestVals{1}(j),...
                                            storeTestVals{2}{i});
                                    end
                                else
                                    curFolder = sprintf('%s%.5f_%s%.5f',paramChoice{1},storeTestVals{1}(j),...
                                        paramChoice{2},storeTestVals{2}(i));
                                end
                            end
                            if repeats > 1 && ~NShiftLogic
                                curFolder = sprintf('%s_repeat%d',curFolder,k);
                                NLoc = outStruct.NLoc;
                                mkdir(savePath,curFolder);
                                save(fullfile(savePath,curFolder,'NLoc.mat'),'NLoc')
                            elseif NShiftLogic
                                curFolder = sprintf('%s_shift%dof%d',curFolder,k,NShiftRepeats);
                                mkdir(savePath,curFolder);
                            else
                                mkdir(savePath,curFolder);
                            end
                            save(fullfile(savePath,curFolder,'dataStruct.mat'),'outStruct')
                            saveFEMLog(app, handles,fullfile(savePath,curFolder))
                        end
                    end
                end
            end
            rStored = outStruct.rStored;
            zStored = outStruct.zStored;
            handles.rStored = rStored;
            handles.zStored = zStored;
            handles.r = handles.rStored{handles.curFrame};
            handles.z = handles.zStored{handles.curFrame};
            handles.meshRefineTest = false;
            guidata(hObject,handles)
        end

        % Value changed function: discreteCheckbox
        function discreteCheckbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            handles.discreteLogic = get(hObject,'Value');
            guidata(hObject,handles)
        end

        % Value changed function: dtText
        function dtText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to dtText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of dtText as text
            %        str2double(get(hObject,'String')) returns contents of dtText as a double
            handles.dt = str2double(get(hObject,'String'));
            handles.dtRef = 0.2 * handles.dt;
            handles.numIt = round(handles.totalTime/handles.dtRef) + 1;
            guidata(hObject,handles)
        end

        % Value changed function: f0Text
        function f0Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to f0Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of f0Text as text
            %        str2double(get(hObject,'String')) returns contents of f0Text as a double
            handles.f0 = str2double(get(hObject,'String'));
            guidata(hObject,handles)
        end

        % Value changed function: fPolyText
        function fPolyText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to fPolyText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of fPolyText as text
            %        str2double(get(hObject,'String')) returns contents of fPolyText as a double
            handles.fPoly = str2double(get(hObject,'String'));
            if handles.squishTest
                handles.squishForce = str2double(get(hObject,'String'));
                handles.rStored = handles.rStored(1);
                handles.zStored = handles.zStored(1);
                handles.r = handles.rStored{1};
                handles.z = handles.zStored{1};
                handles.coeffStored = {};
                handles.curFrame = 1;
                handles = updateDisplay(app, handles);
            end
            guidata(hObject,handles)
        end

        % Value changed function: muText
        function muText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to muText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of muText as text
            %        str2double(get(hObject,'String')) returns contents of muText as a double
            handles.mu = str2double(get(hObject,'String'));
            guidata(hObject,handles)
        end

        % Value changed function: polyForceDecayText
        function polyForceDecayText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to polyForceDecayText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of polyForceDecayText as text
            %        str2double(get(hObject,'String')) returns contents of polyForceDecayText as a double
            handles.polyForceDecay = str2double(get(hObject,'String'));
            guidata(hObject,handles)
        end

        % Value changed function: r0Text
        function r0Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to r0Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of r0Text as text
            %        str2double(get(hObject,'String')) returns contents of r0Text as a double
            if ~handles.noSurf
                handles.r0Adh = str2double(get(hObject,'String'));

                sTotal = 2*pi/4;
                startSpacing = .02;
                sSampleInc = sTotal/(round(sTotal/(startSpacing/4)));
                sParam = 0:sSampleInc:sTotal;
                handles.r = .5*sin(pi*sParam/sTotal);
                handles.z = .5*cos(pi*sParam/sTotal) - .05*handles.r0Adh*1e-7/handles.d0; % offset for small initial contact area
                handles.z = handles.z + 0.5;
                handles.z(handles.z < 0) = 0;
                minSpacing = handles.meshVal*handles.r0Adh*1e-7/handles.d0;
                [r,z] = respaceNodes(handles.r,handles.z,0,[startSpacing,minSpacing]);
                [vMeshStruct,pMeshStruct] = meshFromBound(r,z,handles.nodesPerEl);
                handles.r = vMeshStruct.xVec;
                handles.z = vMeshStruct.yVec;
                handles.IEN = vMeshStruct.IEN;
                handles.vMeshStruct = vMeshStruct;
                handles.pMeshStruct = pMeshStruct;
                
                handles.rStored = {};
                handles.zStored = {};
                handles.IENStored = {};
                handles.rStored{1} = handles.r;
                handles.zStored{1} = handles.z;
                handles.IENStored{1} = handles.IEN;
                handles = updateDisplay(app, handles);
            end
            guidata(hObject,handles)
        end

        % Button pushed function: saveData
        function saveData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            savePath = uigetdir('','Choose folder to save r and z data');
            outStruct = handles.outStruct;
            save(fullfile(savePath,'dataStruct.mat'),'outStruct')
            saveFEMLog(app, handles,savePath)
            guidata(hObject,handles)
        end

        % Value changed function: simSlider
        function simSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to simSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            startSlide = get(hObject,'Min');
            endSlide = get(hObject,'Max');
            pos = get(hObject,'Value');
            num = length(handles.rStored);
            handles.curFrame = round((pos/(endSlide-startSlide))*(num-1))+1;
            handles.r = handles.rStored{handles.curFrame};
            handles.z = handles.zStored{handles.curFrame};
            handles.IEN = handles.IENStored{handles.curFrame};
            handles = updateDisplay(app, handles);
            guidata(hObject,handles)
        end

        % Button pushed function: startSim
        function startSim_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to startSim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.simPlot)
            handles.NShift = 0;
            % shiftGap = sqrt(1/handles.N) / 10;
            % handles.NShift = 5*shiftGap;
            handles.timeDecay = inf;
            handles.rStored = handles.rStored(1:handles.curFrame);
            handles.zStored = handles.zStored(1:handles.curFrame);
            handles.IENStored = handles.IENStored(1:handles.curFrame);
            outStruct = spreadCellCalcFEM_new(handles);
%             outStruct = spreadCellTwoPhase(handles);
            handles.rStored = outStruct.rStored;
            handles.zStored = outStruct.zStored;
            handles.IENStored = outStruct.IENStored;
            handles.timeVec = outStruct.timeVec;
            handles.outStruct = outStruct;
            handles.r = handles.rStored{handles.curFrame};
            handles.z = handles.zStored{handles.curFrame};
            guidata(hObject,handles)
        end

        % Value changed function: totalTimeText
        function totalTimeText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to totalTimeText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of totalTimeText as text
            %        str2double(get(hObject,'String')) returns contents of totalTimeText as a double
            handles.totalTime = str2double(get(handles.totalTimeText,'String'));
            handles.numIt = round(handles.totalTime/handles.dtRef) + 1;
            guidata(hObject,handles)
        end

        % Value changed function: volTolText
        function volTolText_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles.volTol = str2double(get(hObject,'String'));
            guidata(hObject,handles);
        end

        % Value changed function: epsBarText
        function epsBarTextValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles.epsFactor = app.epsBarText.Value;
            guidata(hObject,handles)
        end

        % Value changed function: triMeshCheckbox
        function triMeshCheckboxValueChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            handles.triMesh = app.triMeshCheckbox.Value;
            if handles.triMesh
                handles.nodesPerEl = 3;
            else
                handles.nodesPerEl = 4;
            end
            guidata(hObject,handles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SpreadGUI';
            app.figure1.Tag = 'figure1';
            app.simPlot = uiaxes(app.figure1);
            app.simPlot.Tag = 'simPlot';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'fPoly decay constant (in microns) =';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Total time (in s) =';
            app.simSlider = uislider(app.figure1);
            app.simSlider.Limits = [0 1];
            app.simSlider.MajorTicks = [];
            app.simSlider.ValueChangedFcn = createCallbackFcn(app, @simSlider_Callback, true);
            app.simSlider.MinorTicks = [];
            app.simSlider.Tag = 'simSlider';
            app.startSim = uibutton(app.figure1, 'push');
            app.startSim.ButtonPushedFcn = createCallbackFcn(app, @startSim_Callback, true);
            app.startSim.Tag = 'startSim';
            app.startSim.Text = 'Start simulation here';
            app.r0Text = uieditfield(app.figure1, 'text');
            app.r0Text.ValueChangedFcn = createCallbackFcn(app, @r0Text_Callback, true);
            app.r0Text.Tag = 'r0Text';
            app.r0Text.Value = '50';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'r0 (range of adhesion force in nm) =';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'f0 (adh stress magnitude) =';
            app.f0Text = uieditfield(app.figure1, 'text');
            app.f0Text.ValueChangedFcn = createCallbackFcn(app, @f0Text_Callback, true);
            app.f0Text.Tag = 'f0Text';
            app.f0Text.Value = '60000';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'fPoly (protrusion stress magnitude) =';
            app.fPolyText = uieditfield(app.figure1, 'text');
            app.fPolyText.ValueChangedFcn = createCallbackFcn(app, @fPolyText_Callback, true);
            app.fPolyText.Tag = 'fPolyText';
            app.fPolyText.Value = '3000';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'dt (in terms of Courant number)=';
            app.dtText = uieditfield(app.figure1, 'text');
            app.dtText.ValueChangedFcn = createCallbackFcn(app, @dtText_Callback, true);
            app.dtText.Tag = 'dtText';
            app.dtText.Value = '0.1';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'mu (viscosity in Pa-s) =';
            app.muText = uieditfield(app.figure1, 'text');
            app.muText.ValueChangedFcn = createCallbackFcn(app, @muText_Callback, true);
            app.muText.Tag = 'muText';
            app.muText.Value = '200';
            app.polyForceDecayText = uieditfield(app.figure1, 'text');
            app.polyForceDecayText.ValueChangedFcn = createCallbackFcn(app, @polyForceDecayText_Callback, true);
            app.polyForceDecayText.Tag = 'polyForceDecayText';
            app.polyForceDecayText.Value = '0.8';
            app.NText = uieditfield(app.figure1, 'text');
            app.NText.ValueChangedFcn = createCallbackFcn(app, @NText_Callback, true);
            app.NText.Tag = 'NText';
            app.NText.Value = '1000';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'rho (ligand density in # per square micron) =';
            app.totalTimeText = uieditfield(app.figure1, 'text');
            app.totalTimeText.ValueChangedFcn = createCallbackFcn(app, @totalTimeText_Callback, true);
            app.totalTimeText.Tag = 'totalTimeText';
            app.totalTimeText.Value = '200';
            app.saveData = uibutton(app.figure1, 'push');
            app.saveData.ButtonPushedFcn = createCallbackFcn(app, @saveData_Callback, true);
            app.saveData.Tag = 'saveData';
            app.saveData.Text = 'Save Data';
            app.batchRun = uibutton(app.figure1, 'push');
            app.batchRun.ButtonPushedFcn = createCallbackFcn(app, @batchRun_Callback, true);
            app.batchRun.Tag = 'batchRun';
            app.batchRun.Text = 'Run batch';
            app.volTolText = uieditfield(app.figure1, 'text');
            app.volTolText.ValueChangedFcn = createCallbackFcn(app, @volTolText_Callback, true);
            app.volTolText.Tag = 'volTolText';
            app.volTolText.Value = '0.5';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Volume tolerance (maximum % deviation)';
            app.discreteCheckbox = uicheckbox(app.figure1);
            app.discreteCheckbox.ValueChangedFcn = createCallbackFcn(app, @discreteCheckbox_Callback, true);
            app.discreteCheckbox.Tag = 'discreteCheckbox';
            app.discreteCheckbox.Text = {'Discrete adhesion'; 'sites'};
            app.triMeshCheckbox = uicheckbox(app.figure1);
            app.triMeshCheckbox.ValueChangedFcn = createCallbackFcn(app, @triMeshCheckboxValueChanged, true);
            app.triMeshCheckbox.Tag = 'discreteCheckbox';
            app.triMeshCheckbox.Text = {'Tri mesh '; '(under development)'};
            app.epsBarText = uieditfield(app.figure1, 'text');
            app.epsBarText.ValueChangedFcn = createCallbackFcn(app, @epsBarTextValueChanged, true);
            app.epsBarText.Tag = 'volTolText';
            app.epsBarText.Value = '1';
            app.text13_2 = uilabel(app.figure1);
            app.text13_2.Tag = 'text13';
            app.text13_2.Text = 'epsilon bar (enforces incompressibility, see doc)';
            app.PlaybackLabel = uilabel(app.figure1);
            app.PlaybackLabel.Text = 'Playback';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpreadGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SpreadGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end